classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LoadTxt                         matlab.ui.control.Label
        Result                          matlab.ui.control.Label
        ImportFileButton                matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        ClassifyButton                  matlab.ui.control.Button
        OptimizationDropDown            matlab.ui.control.DropDown
        OptimizasyonSeimiDropDownLabel  matlab.ui.control.Label
        NetworkDropDown                 matlab.ui.control.DropDown
        ASeimiDropDownLabel             matlab.ui.control.Label
        AlzheimerSnflandrmaUygulamasLabel  matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end


    properties (Access = public)
        modelsDir
        selectedImagePath
        currentModel
    end
    methods (Access = private)
        function loadModelBasedOnSelection(app)
            selectedNetwork = app.NetworkDropDown.Value;
            selectedOptimizer = app.OptimizationDropDown.Value;

            % Construct the model name
            modelName = strcat(selectedNetwork, '_', selectedOptimizer, '.mat');

            % Build the full path to the model file
            modelFilePath = fullfile(app.modelsDir, modelName);

            % Load the model
            loadedData = load(modelFilePath);
            app.currentModel = loadedData.TrainedNetwork;

            % Konsola 
            app.LoadTxt.Text = sprintf('%s yüklendi.', modelName);

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.modelsDir = 'C:\Users\zeira\Desktop\DeepLearning\models';
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            % Dosya seçici diyalog aç
            [file, path] = uigetfile({'*.jpg;*.png;*.jpeg', 'Image Files (*.jpg, *.png, *.jpeg)'});

            % Kullanıcı bir dosya seçtiyse
            if isequal(file, 0)
                disp('User selected Cancel');
            else
                % Seçilen dosyanın tam yolunu oluştur
                app.selectedImagePath = fullfile(path, file); 

                % Image bileşeninde görseli göster
                app.Image.ImageSource = app.selectedImagePath; 

                % Resim yüklenince yazıyı sil 
                app.Result.Text = '';
            end
        end

        % Callback function: ClassifyButton, UIFigure
        function ClassifyButtonPushed(app, event)
            
            app.loadModelBasedOnSelection();
            model = app.currentModel;
            
            % Görseli oku
            img = imread(app.selectedImagePath);
            
            % Görseli yeniden boyutlandır ve gerekiyorsa griye çevir
            img = imresize(img, [227 227]);

            if size(img, 3) == 3
                img = rgb2gray(img);
            end

            img = reshape(img, [227, 227, 1]);

            % Sınıflandırma işlemini gerçekleştir
            [label, score] = classify(model, img);

            app.Result.Text = sprintf('%s', label);
        end

        % Value changed function: NetworkDropDown
        function NetworkDropDownValueChanged(app, event)
            app.loadModelBasedOnSelection();
        end

        % Value changed function: OptimizationDropDown
        function OptimizationDropDownValueChanged(app, event)
            app.loadModelBasedOnSelection();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.AlzheimerSnflandrmaUygulamasLabel = uilabel(app.UIFigure);
            app.AlzheimerSnflandrmaUygulamasLabel.Text = 'Alzheimer Sınıflandırma Uygulaması';
            app.ASeimiDropDownLabel = uilabel(app.UIFigure);
            app.ASeimiDropDownLabel.Text = 'Ağ Seçimi ';
            app.NetworkDropDown = uidropdown(app.UIFigure);
            app.NetworkDropDown.Items = {'MobileNetV2', 'MyCNN'};
            app.NetworkDropDown.ValueChangedFcn = createCallbackFcn(app, @NetworkDropDownValueChanged, true);
            app.NetworkDropDown.Tag = 'NetworkDropDown';
            app.NetworkDropDown.Value = 'MobileNetV2';
            app.OptimizasyonSeimiDropDownLabel = uilabel(app.UIFigure);
            app.OptimizasyonSeimiDropDownLabel.Text = 'Optimizasyon Seçimi';
            app.OptimizationDropDown = uidropdown(app.UIFigure);
            app.OptimizationDropDown.Items = {'RMSprop', 'Adam', 'SGDM'};
            app.OptimizationDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimizationDropDownValueChanged, true);
            app.OptimizationDropDown.Tag = 'OptimizationDropDown';
            app.OptimizationDropDown.Value = 'RMSprop';
            app.ClassifyButton = uibutton(app.UIFigure, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Icon = fullfile(pathToMLAPP, 'u_search.png');
            app.ClassifyButton.Text = 'Sınıflandır';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'no shadow TIFF.png');
            app.ImportFileButton = uilabel(app.UIFigure);
            app.ImportFileButton.Text = 'Dosya Yükle';
            app.Result = uilabel(app.UIFigure);
            app.Result.Text = '';
            app.LoadTxt = uilabel(app.UIFigure);
            app.LoadTxt.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end